classdef GUIDECV_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uitoolbar2            matlab.ui.container.Toolbar
        toggleToolZoomIn      matlab.ui.container.toolbar.ToggleTool
        toggleToolZoomOut     matlab.ui.container.toolbar.ToggleTool
        toggleToolPan         matlab.ui.container.toolbar.ToggleTool
        toggleToolRotate      matlab.ui.container.toolbar.ToggleTool
        textHelpText          matlab.ui.control.Label
        buttonForeground      matlab.ui.control.Button
        buttonStart           matlab.ui.control.Button
        buttonVanishingPoint  matlab.ui.control.Button
        buttonInnerRectangle  matlab.ui.control.Button
        buttonReset           matlab.ui.control.Button
        textTitle             matlab.ui.control.Label
        buttonQuit            matlab.ui.control.Button
        buttonSelectImage     matlab.ui.control.Button
        axes3                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function slider1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider3_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.toggleToolZoomIn, app.toggleToolZoomOut, app.toggleToolPan, app.toggleToolRotate];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUIDECV_OpeningFcn(app, varargin)
            % This is executed before GUI appears.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUIDECV (see VARARGIN)
            
            % Choose default command line output for GUIDECV
            handles.output = hObject;
            global pos1
            global pos2
            global pos3
            global pos4
            global max_y
            global max_x
            global min_y
            global min_x
            global x_max
            global y_max
            global vanishing_point
            global m
            global n
            global p
            global min_y_fore
            global max_y_fore
            global min_x_fore
            global max_x_fore
            global poss1
            global poss2
            global poss3
            global poss4
            global estimatedVertex
            set(hObject,'toolbar','figure') %Show Toolbar
            cameratoolbar('NoReset')%Show camera toolbar without presets
            global img
            global test
            global FGVertex
            global outFG
            global foreobj
            global pic_num
            global foreground
            global background
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: buttonSelectImage
        function buttonSelectImage_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes3);
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'select a picture');
            str=[pathname filename];
            % Check if the file is empty
            global img
            img = imread(str);
            % imshow(im)
            if isequal(filename,0)||isequal(pathname,0)
                warndlg('please select a picture first!','warning');
                return;
            else
                img = imread(str);
                imshow(img);
            end
        end

        % Button pushed function: buttonQuit
        function buttonQuit_Callback(app, event)
            % --- Executes on button press in pushbutton2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close all;
        end

        % Button pushed function: buttonReset
        function buttonReset_Callback(app, event)
            % --- Executes on button press in pushbutton3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes(handles.axes3); %Specify the coordinates that need to be cleared
            cla reset;
            set(handles.axes3,'visible','off');
        end

        % Button pushed function: buttonInnerRectangle
        function buttonInnerRectangle_Callback(app, event)
            % --- Executes on button press in pushbutton4.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes3 = gca;%Get the current axis
            h=imrect;%The mouse turns into a cross to select the region of interest
            
            
            %There will be a rectangular box that can be dragged and resized
            
            pos=getPosition(h);%pos has four values，are the coordinates of the upper left corner of the rectangular box x y and the width and height of the box
            hold on
            global pos1
            global pos2
            global pos3
            global pos4
            pos1=[pos(1),pos(2)]
            %pos1=[pos(2),pos(1)]
            pos2=[pos(1)+pos(3),pos(2)]
            %pos2=[pos(2)+pos(4),pos(1)]
            pos3=[pos(1)+pos(3),pos(2)+pos(4)]
            %pos3=[pos(2),pos(1)+pos(3)]
            pos4=[pos(1),pos(2)+pos(4)]
            %pos4=[pos(2)+pos(4),pos(1)+pos(3)]
            global max_y
            global max_x
            global min_y
            global min_x
             min_y=pos(2);
             max_y=pos(2)+pos(4);
             min_x=pos(1);
             max_x=pos(1)+pos(3);
        end

        % Button pushed function: buttonVanishingPoint
        function buttonVanishingPoint_Callback(app, event)
            % --- Executes on button press in pushbutton5.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [x,y] = ginput(1);
            
            hold on
            a=min(x);
            b=min(y);%Get the pixel coordinates of a point clicked by the mouse, x saves the abscissa vector, y saves the ordinate vector
            a=floor(a)
            b=floor(b)
            T=[a,b]
            global vanishing_point
            global estimatedVertex
            vanishing_point=[b,a];
            % plot(a,b,'bo')
            global pos1
            global pos2
            global pos3
            global pos4
            
            global max_y
            global max_x
            global min_y
            global min_x
            
            global img
            global x_max
            global y_max
            global m
            global n
            global p
            global pic_num
            global foreground
             [m,n,p]=size(img)
                x_max = n
                y_max = m
            
               x_vp = a;
               y_vp = b;
            
            coordinat_sp(1,:)= pos4; %1
            coordinat_sp(2,:) = pos3; %2
            coordinat_sp(3,:) = pos2; %8
            coordinat_sp(4,:) = pos1; %7
            
            
            % gradient for the foreground
            for i =1:4
                gradient(i) = (coordinat_sp(i,2)-y_vp)/(coordinat_sp(i,1)-x_vp)
            end
            %plot(x_sp,y_sp,'b')
            estimatedVertex = zeros(2,12);
            estimatedVertex(:,1) = pos4;
            estimatedVertex(:,2) = pos3;
            estimatedVertex(:,8) = pos2;
            estimatedVertex(:,7) = pos1;
            
            estimatedVertex(:,3) = [(y_max-y_vp)/gradient(1) + x_vp; y_max];
            estimatedVertex(:,5) = [1; (1-x_vp)*gradient(1) + y_vp];
            estimatedVertex(:,4) = [(y_max-y_vp)/gradient(2) + x_vp; y_max];
            estimatedVertex(:,6) = [x_max; (x_max-x_vp)*gradient(2) + y_vp];
            estimatedVertex(:,10) = [(1-y_vp)/gradient(3) + x_vp; 1];
            estimatedVertex(:,12) = [x_max; (x_max-x_vp)*gradient(3) + y_vp];
            estimatedVertex(:,9) = [(1-y_vp)/gradient(4) + x_vp; 1];
            estimatedVertex(:,11) = [1; (1-x_vp)*gradient(4) + y_vp];
            
            
            for i = 1:12
                plot([estimatedVertex(1,i),x_vp],[estimatedVertex(2,i),y_vp],'r-','lineWidth',3)
                text(estimatedVertex(1,i),estimatedVertex(2,i),num2str(i))
            end
            
             plot([estimatedVertex(1,5),estimatedVertex(1,11)],[estimatedVertex(2,5),estimatedVertex(2,11)],'r-','lineWidth',3)
             plot([estimatedVertex(1,9),estimatedVertex(1,10)],[estimatedVertex(2,9),estimatedVertex(2,10)],'r-','lineWidth',3)
             plot([estimatedVertex(1,6),estimatedVertex(1,12)],[estimatedVertex(2,6),estimatedVertex(2,12)],'r-','lineWidth',3)
             plot([estimatedVertex(1,3),estimatedVertex(1,4)],[estimatedVertex(2,3),estimatedVertex(2,4)],'r-','lineWidth',3)
            
             pic_num=ceil(max(size(img(:,:)))/5);
             foreground = {};
        end

        % Button pushed function: buttonStart
        function buttonStart_Callback(app, event)
            % --- Executes on button press in pushbutton7.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global img
            global x_max
            global y_max
            global max_y
            global max_x
            global min_y
            global min_x
            global m
            global n
            global p
            global vanishing_point
            global FGVertex
            global outFG
            global foreobj
            global test
            global foreground
            global background
            global pic_num
            hold off
            x_max=floor(x_max);
            y_max=floor(y_max);
            max_y=floor(max_y);
            max_x=floor(max_x);
            min_y=floor(min_y);
            min_x=floor(min_x);
            vanishing_point=floor(vanishing_point);
            box=[min_y,max_y,min_x,max_x];
            mx=0;my=0;mz=0;rx=0;ry=0;rz=0;
            geo=[mx,my,mz,rx,ry,rz];
            
            
             [out,test]=Tour_into_the_3d_picture(img,vanishing_point,box);
             f = figure;
             surface_3d(out)
             hold on
             if numel(foreground) ~= 0
                   [X,Y,Z,C,outputAlpha] = surfaceFG_3d(foreground,out,FGVertex,foreobj,test,pic_num);
                    h = surface(X,Y,Z,C);
                    set(h,'LineStyle','none');
                    h.AlphaData = outputAlpha;
                    h.FaceAlpha = 'flat';
             end
              cameratoolbar('NoReset')
             tb = cameratoolbar(f);
             view(0,-80)
             setCamera(out)
             axis off
        end

        % Button pushed function: buttonForeground
        function buttonForeground_Callback(app, event)
            % --- Executes on button press in pushbutton8.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
              h=imrect;


            global img
            poss=getPosition(h);
            hold on
            global poss1
            global poss2
            global poss3
            global poss4
            poss1=[poss(1),poss(2)]
            %pos1=[pos(2),pos(1)]
            poss2=[poss(1)+poss(3),poss(2)]
            %pos2=[pos(2)+pos(4),pos(1)]
            poss3=[poss(1)+poss(3),poss(2)+poss(4)]
            %pos3=[pos(2),pos(1)+pos(3)]
            poss4=[poss(1),poss(2)+poss(4)]
            %pos4=[pos(2)+pos(4),pos(1)+pos(3)]
            global vanishing_point
            global min_y_fore
            global max_y_fore
            global min_x_fore
            global max_x_fore
            global estimatedVertex
            global FGVertex
            global outFG
            global foreobj
            global test
            global foreground
            global background
            min_y_fore=poss(2);
            max_y_fore=poss(2)+poss(4);
            min_x_fore=poss(1);
            max_x_fore=poss(1)+poss(3);
            [foreground, img]= maskBackground(img, min_y_fore,max_y_fore,min_x_fore,max_x_fore);
            [FGVertex,foreobj]  = foregroundobj(foreground,vanishing_point,estimatedVertex,min_y_fore,max_y_fore,min_x_fore,max_x_fore);
        end

        % Callback function: toggleToolRotate
        function toggleToolRotate_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable rotation based on the
            % tool's current state.
            state = app.toggleToolRotate.State;
            rotate3d(app.figure1, char(state));
        end

        % Callback function: toggleToolPan
        function toggleToolPan_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.toggleToolPan.State;
            pan(app.figure1, char(state));
        end

        % Callback function: toggleToolZoomOut
        function toggleToolZoomOut_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.toggleToolZoomOut.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: toggleToolZoomIn
        function toggleToolZoomIn_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.toggleToolZoomIn.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUIDECV';
            app.figure1.Tag = 'figure1';
            app.uitoolbar2 = uitoolbar(app.figure1);
            app.uitoolbar2.Tag = 'uitoolbar2';
            app.toggleToolZoomIn = uitoggletool(app.uitoolbar2);
            app.toggleToolZoomIn.Tag = 'uitoggletool3';
            app.toggleToolZoomIn.Tooltip = '放大';
            app.toggleToolZoomIn.ClickedCallback = createCallbackFcn(app, @toggleToolZoomIn_ClickedCallback, true);
            app.toggleToolZoomIn.Icon = 'uitoggletool3_image.png';
            app.toggleToolZoomOut = uitoggletool(app.uitoolbar2);
            app.toggleToolZoomOut.Tag = 'uitoggletool4';
            app.toggleToolZoomOut.Tooltip = '缩小';
            app.toggleToolZoomOut.ClickedCallback = createCallbackFcn(app, @toggleToolZoomOut_ClickedCallback, true);
            app.toggleToolZoomOut.Icon = 'uitoggletool4_image.png';
            app.toggleToolPan = uitoggletool(app.uitoolbar2);
            app.toggleToolPan.Tag = 'uitoggletool5';
            app.toggleToolPan.Tooltip = '平移';
            app.toggleToolPan.ClickedCallback = createCallbackFcn(app, @toggleToolPan_ClickedCallback, true);
            app.toggleToolPan.Icon = 'uitoggletool5_image.png';
            app.toggleToolRotate = uitoggletool(app.uitoolbar2);
            app.toggleToolRotate.Tag = 'uitoggletool6';
            app.toggleToolRotate.Tooltip = '三维旋转';
            app.toggleToolRotate.ClickedCallback = createCallbackFcn(app, @toggleToolRotate_ClickedCallback, true);
            app.toggleToolRotate.Icon = 'uitoggletool6_image.png';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.buttonSelectImage = uibutton(app.figure1, 'push');
            app.buttonSelectImage.ButtonPushedFcn = createCallbackFcn(app, @buttonSelectImage_Callback, true);
            app.buttonSelectImage.Tag = 'pushbutton1';
            app.buttonSelectImage.Text = 'Select Image';
            app.buttonQuit = uibutton(app.figure1, 'push');
            app.buttonQuit.ButtonPushedFcn = createCallbackFcn(app, @buttonQuit_Callback, true);
            app.buttonQuit.Tag = 'pushbutton2';
            app.buttonQuit.Text = 'Quit';
            app.textTitle = uilabel(app.figure1);
            app.textTitle.Tag = 'text2';
            app.textTitle.Text = {'  Tour into the picture'; 'TUM CV challenge SS22 '};
            app.buttonReset = uibutton(app.figure1, 'push');
            app.buttonReset.ButtonPushedFcn = createCallbackFcn(app, @buttonReset_Callback, true);
            app.buttonReset.Tag = 'pushbutton3';
            app.buttonReset.Text = 'Reset';
            app.buttonInnerRectangle = uibutton(app.figure1, 'push');
            app.buttonInnerRectangle.ButtonPushedFcn = createCallbackFcn(app, @buttonInnerRectangle_Callback, true);
            app.buttonInnerRectangle.Tag = 'pushbutton4';
            app.buttonInnerRectangle.Text = 'Inner rectangle';
            app.buttonVanishingPoint = uibutton(app.figure1, 'push');
            app.buttonVanishingPoint.ButtonPushedFcn = createCallbackFcn(app, @buttonVanishingPoint_Callback, true);
            app.buttonVanishingPoint.Tag = 'pushbutton5';
            app.buttonVanishingPoint.Text = 'Vanishing point';
            app.buttonStart = uibutton(app.figure1, 'push');
            app.buttonStart.ButtonPushedFcn = createCallbackFcn(app, @buttonStart_Callback, true);
            app.buttonStart.Tag = 'pushbutton7';
            app.buttonStart.Text = 'Start';
            app.buttonForeground = uibutton(app.figure1, 'push');
            app.buttonForeground.ButtonPushedFcn = createCallbackFcn(app, @buttonForeground_Callback, true);
            app.buttonForeground.Tag = 'pushbutton8';
            app.buttonForeground.Text = 'Foreground';
            app.textHelpText = uilabel(app.figure1);
            app.textHelpText.Tag = 'text4';
            app.textHelpText.Text = {'Step1: Click Select Image, choose the picture you are interested in'; ''; 'Step2: Click Inner rectangle, frame a rectangle close to the center'; ''; 'Step3: Click Vanishing point, select a point within the rectangle you just outlined as the vanishing point'; ''; 'Step4: Click Foreground, select a foreground object model %You can also skip this step without selecting the foreground object'; ''; 'Step5: Click Start, you will see the 3d reconstructed picture'; ''; 'Step6: Click Reset, repeat step 1-5'; ''; 'Step7: Click Quit, close the program'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIDECV_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUIDECV_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end